classdef BasicCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Button_1              matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        Button_5              matlab.ui.control.Button
        Button_6              matlab.ui.control.Button
        Button_7              matlab.ui.control.Button
        Button_8              matlab.ui.control.Button
        Button_9              matlab.ui.control.Button
        Button_0              matlab.ui.control.Button
        AddButton             matlab.ui.control.Button
        SubButton             matlab.ui.control.Button
        MulButton             matlab.ui.control.Button
        DivButton             matlab.ui.control.Button
        ModButton             matlab.ui.control.Button
        CButton               matlab.ui.control.Button
        Number1EditField      matlab.ui.control.NumericEditField
        Number2EditField      matlab.ui.control.NumericEditField
        OperationEditField    matlab.ui.control.EditField
        EqualButton           matlab.ui.control.Button
        AnsEditField          matlab.ui.control.NumericEditField
        BasicCalculatorLabel  matlab.ui.control.Label
    end


    methods (Access = private)
    
        function Num = MergeDigits(app,digitsVector)
            N=length(digitsVector);
            Num = 0;
            for i = 1:N
                Num = Num + digitsVector(i)*10^(N-i);
            end
        end
        
    end


    methods (Access = private)

        % Callback function
        function XButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: EqualButton
        function EqualButtonPushed(app, event)
            number1=app.Number1EditField.Value;
            number2=app.Number2EditField.Value;
            switch app.OperationEditField.Value
                case '+'; app.AnsEditField.Value = number1 + number2;
                case '-'; app.AnsEditField.Value = number1 - number2;
                case '*'; app.AnsEditField.Value = number1 * number2;
                case '/'; app.AnsEditField.Value = number1 / number2;
                case '%'; app.AnsEditField.Value = mod(number1, number2);
            end
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            if strcmp(app.OperationEditField.Value, '')
                app.Number1EditField.Value = MergeDigits(app,[app.Number1EditField.Value 1]);
            else
                app.Number2EditField.Value = MergeDigits(app,[app.Number1EditField.Value 1]);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if strcmp(app.OperationEditField.Value, '')
                app.Number1EditField.Value = MergeDigits(app,[app.Number2EditField.Value 2]);
            else
                app.Number2EditField.Value = MergeDigits(app,[app.Number2EditField.Value 2]);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if strcmp(app.OperationEditField.Value, '')
                app.Number1EditField.Value = MergeDigits(app,[app.Number3EditField.Value 3]);
            else
                app.Number2EditField.Value = MergeDigits(app,[app.Number3EditField.Value 3]);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if strcmp(app.OperationEditField.Value, '')
                app.Number1EditField.Value = 4;
            else
                app.Number2EditField.Value = 4;
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if strcmp(app.OperationEditField.Value, '')
                app.Number1EditField.Value = 5;
            else
                app.Number2EditField.Value = 5;
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if strcmp(app.OperationEditField.Value, '')
                app.Number1EditField.Value = 6;
            else
                app.Number2EditField.Value = 6;
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if strcmp(app.OperationEditField.Value, '')
                app.Number1EditField.Value = 7;
            else
                app.Number2EditField.Value = 7;
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if strcmp(app.OperationEditField.Value, '')
                app.Number1EditField.Value = 8;
            else
                app.Number2EditField.Value = 8;
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            if strcmp(app.OperationEditField.Value, '')
                app.Number1EditField.Value = 9;
            else
                app.Number2EditField.Value = 9;
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.OperationEditField.Value = '+';
        end

        % Button pushed function: SubButton
        function SubButtonPushed(app, event)
            app.OperationEditField.Value = '-';
        end

        % Button pushed function: MulButton
        function MulButtonPushed(app, event)
            app.OperationEditField.Value = '*';
        end

        % Button pushed function: DivButton
        function DivButtonPushed(app, event)
            app.OperationEditField.Value = '/';
            
        end

        % Button pushed function: ModButton
        function ModButtonPushed(app, event)
            app.OperationEditField.Value = '%';
        end

        % Button pushed function: Button_0
        function Button_0Pushed(app, event)
            if strcmp(app.OperationEditField.Value, '')
                app.Number1EditField.Value = 0;
            else
                app.Number2EditField.Value = 0;
            end
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            app.Number1EditField.Value = 0;
            app.Number2EditField.Value = 0;
            app.OperationEditField.Value = '';
            app.AnsEditField.Value = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_0 = uibutton(app.UIFigure, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @Button_0Pushed, true);
            app.Button_0.Text = '0';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '+';
            app.SubButton = uibutton(app.UIFigure, 'push');
            app.SubButton.ButtonPushedFcn = createCallbackFcn(app, @SubButtonPushed, true);
            app.SubButton.Text = '-';
            app.MulButton = uibutton(app.UIFigure, 'push');
            app.MulButton.ButtonPushedFcn = createCallbackFcn(app, @MulButtonPushed, true);
            app.MulButton.Text = '*';
            app.DivButton = uibutton(app.UIFigure, 'push');
            app.DivButton.ButtonPushedFcn = createCallbackFcn(app, @DivButtonPushed, true);
            app.DivButton.Text = '/';
            app.ModButton = uibutton(app.UIFigure, 'push');
            app.ModButton.ButtonPushedFcn = createCallbackFcn(app, @ModButtonPushed, true);
            app.ModButton.Text = '%';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.Number1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Number2EditField = uieditfield(app.UIFigure, 'numeric');
            app.OperationEditField = uieditfield(app.UIFigure, 'text');
            app.EqualButton = uibutton(app.UIFigure, 'push');
            app.EqualButton.ButtonPushedFcn = createCallbackFcn(app, @EqualButtonPushed, true);
            app.EqualButton.Text = '=';
            app.AnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.BasicCalculatorLabel = uilabel(app.UIFigure);
            app.BasicCalculatorLabel.Text = 'Basic Calculator';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BasicCalculator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end