classdef WAM1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Button_1             matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        Button_3             matlab.ui.control.Button
        Button_4             matlab.ui.control.Button
        Button_5             matlab.ui.control.Button
        Button_6             matlab.ui.control.Button
        Button_7             matlab.ui.control.Button
        Button_8             matlab.ui.control.Button
        Button_9             matlab.ui.control.Button
        WhackAMoleLabel      matlab.ui.control.Label
        PlayButton           matlab.ui.control.Button
        QuitButton           matlab.ui.control.Button
        LevelEditFieldLabel  matlab.ui.control.Label
        LevelEditField       matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if app.LevelEditField.Value == 1
                app.Button_1.Text = 'I';
                app.Button_5.Icon = 'WhackAMoleFace.png';
                app.Button_5.Text = '';
                app.Button_9.Text = 'You';
            elseif app.LevelEditField.Value == 2
                app.Button_5.Text = 'Hate';
                app.Button_5.Icon = '';
            else    
                app.Button_1.Text = '';
                app.Button_5.Text = '';
                app.Button_9.Text = '';
            end
                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.Text = '';
            app.WhackAMoleLabel = uilabel(app.UIFigure);
            app.WhackAMoleLabel.Text = 'Whack-A-Mole';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.LevelEditFieldLabel = uilabel(app.UIFigure);
            app.LevelEditFieldLabel.Text = 'Level';
            app.LevelEditField = uieditfield(app.UIFigure, 'numeric');
            app.LevelEditField.Limits = [1 9];
            app.LevelEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = WAM1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end