classdef TTT_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        Space_1               matlab.ui.control.Button
        Space_2               matlab.ui.control.Button
        Space_3               matlab.ui.control.Button
        Space_4               matlab.ui.control.Button
        Space_5               matlab.ui.control.Button
        Space_6               matlab.ui.control.Button
        Space_7               matlab.ui.control.Button
        Space_8               matlab.ui.control.Button
        Space_9               matlab.ui.control.Button
        PlayerLabel           matlab.ui.control.Label
        PlayerVal             matlab.ui.control.Label
        PlayerEditFieldLabel  matlab.ui.control.Label
        PlayerEditField       matlab.ui.control.NumericEditField
    end


    methods (Access = private)
    
        function markBoard(app,spaceObj)
            player = app.PlayerEditField.Value;
            if player == 1
                spaceObj.Icon = 'X_Fig.PNG';
            elseif player == -1
                spaceObj.Icon = 'O_Fig.PNG';
            else
                spaceObj.Icon = '';
            end
            app.PlayerEditField.Value = -player;
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            I = imshow('TTTboard.gif', 'Parent', app.UIAxes);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Button pushed function: Space_1
        function Space_1ButtonPushed(app, event)
            markBoard(app,app.Space_1)
        end

        % Button pushed function: Space_2
        function Space_2ButtonPushed(app, event)
            markBoard(app,app.Space_2)
        end

        % Button pushed function: Space_3
        function Space_3ButtonPushed(app, event)
            markBoard(app,app.Space_3)
        end

        % Button pushed function: Space_4
        function Space_4ButtonPushed(app, event)
            markBoard(app,app.Space_4)
        end

        % Button pushed function: Space_5
        function Space_5ButtonPushed(app, event)
            markBoard(app,app.Space_5)
        end

        % Button pushed function: Space_6
        function Space_6ButtonPushed(app, event)
            markBoard(app,app.Space_6)
        end

        % Button pushed function: Space_7
        function Space_7ButtonPushed(app, event)
            markBoard(app,app.Space_7)
        end

        % Button pushed function: Space_8
        function Space_8ButtonPushed(app, event)
            markBoard(app,app.Space_8)
        end

        % Button pushed function: Space_9
        function Space_9ButtonPushed(app, event)
            markBoard(app,app.Space_9)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Space_1 = uibutton(app.UIFigure, 'push');
            app.Space_1.ButtonPushedFcn = createCallbackFcn(app, @Space_1ButtonPushed, true);
            app.Space_1.Text = '';
            app.Space_2 = uibutton(app.UIFigure, 'push');
            app.Space_2.ButtonPushedFcn = createCallbackFcn(app, @Space_2ButtonPushed, true);
            app.Space_2.Text = '';
            app.Space_3 = uibutton(app.UIFigure, 'push');
            app.Space_3.ButtonPushedFcn = createCallbackFcn(app, @Space_3ButtonPushed, true);
            app.Space_3.Text = '';
            app.Space_4 = uibutton(app.UIFigure, 'push');
            app.Space_4.ButtonPushedFcn = createCallbackFcn(app, @Space_4ButtonPushed, true);
            app.Space_4.Text = '';
            app.Space_5 = uibutton(app.UIFigure, 'push');
            app.Space_5.ButtonPushedFcn = createCallbackFcn(app, @Space_5ButtonPushed, true);
            app.Space_5.Text = '';
            app.Space_6 = uibutton(app.UIFigure, 'push');
            app.Space_6.ButtonPushedFcn = createCallbackFcn(app, @Space_6ButtonPushed, true);
            app.Space_6.Text = '';
            app.Space_7 = uibutton(app.UIFigure, 'push');
            app.Space_7.ButtonPushedFcn = createCallbackFcn(app, @Space_7ButtonPushed, true);
            app.Space_7.Text = '';
            app.Space_8 = uibutton(app.UIFigure, 'push');
            app.Space_8.ButtonPushedFcn = createCallbackFcn(app, @Space_8ButtonPushed, true);
            app.Space_8.Text = '';
            app.Space_9 = uibutton(app.UIFigure, 'push');
            app.Space_9.ButtonPushedFcn = createCallbackFcn(app, @Space_9ButtonPushed, true);
            app.Space_9.Text = '';
            app.PlayerLabel = uilabel(app.UIFigure);
            app.PlayerLabel.Text = 'Player: ';
            app.PlayerVal = uilabel(app.UIFigure);
            app.PlayerVal.Text = '1';
            app.PlayerEditFieldLabel = uilabel(app.UIFigure);
            app.PlayerEditFieldLabel.Text = 'Player:';
            app.PlayerEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlayerEditField.ValueDisplayFormat = '%.0f';
            app.PlayerEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = TTT_APP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end