classdef TTT_Battle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PlayerXDropDownLabel         matlab.ui.control.Label
        PlayerXDropDown              matlab.ui.control.DropDown
        PlayerODropDownLabel         matlab.ui.control.Label
        PlayerODropDown              matlab.ui.control.DropDown
        TicTacToeBattleLabel         matlab.ui.control.Label
        XAISwitch                    matlab.ui.control.Switch
        OAISwitch                    matlab.ui.control.Switch
        NumberofGamesEditFieldLabel  matlab.ui.control.Label
        NumberofGamesEditField       matlab.ui.control.NumericEditField
        UIAxes                       matlab.ui.control.UIAxes
        BattleButton                 matlab.ui.control.Button
        AIStrategyButtonGroup        matlab.ui.container.ButtonGroup
        XAIButton1                   matlab.ui.control.RadioButton
        XAIButton2                   matlab.ui.control.RadioButton
        XAIButton3                   matlab.ui.control.RadioButton
        ButtonGroup                  matlab.ui.container.ButtonGroup
        OAIButton1                   matlab.ui.control.RadioButton
        OAIButton2                   matlab.ui.control.RadioButton
        OAIButton3                   matlab.ui.control.RadioButton
        AILabel                      matlab.ui.control.Label
        ResultsTable                 matlab.ui.control.Table
    end

    
    properties (Access = private)
        Roster = dataset('File','C:/Users/coxgw10/Dropbox/Teaching/F_2018/MA310/MATLAB/Homework Programs/TicTacToe/App/MA310Roster.csv','Delimiter',',');
    end
    
    methods (Access = private)
        
        function strategy = getStrategy(app,player)
            if player == 1
                if app.XAIButton1.Value; strategy = '1'; end
                if app.XAIButton2.Value; strategy = '2'; end
                if app.XAIButton3.Value; strategy = '3'; end
            else
                if app.OAIButton1.Value; strategy = '1'; end
                if app.OAIButton2.Value; strategy = '2'; end
                if app.OAIButton3.Value; strategy = '3'; end
            end
        end
        
        function TTT_Game_Simulator(app,player_1,player_1_strategy,player_2,player_2_strategy)
            numOfGames = app.NumberofGamesEditField.Value;
            results = zeros(1,numOfGames);
            player_1_times = zeros(1,numOfGames);
            player_2_times = zeros(1,numOfGames);
            runs = 1:numOfGames;
            XWins = 0;
            XWinningPer = 0;
            OWins = 0;
            OWinningPer = 0;
            Ties = 0;
            addpath(['Moves/',player_1]);
            addpath(['Moves/',player_2]);
            addpath('Moves/Key');
            NoYes = {'No','Yes','<-error'};
            Xsubmitted = exist(['TTT_Move',player_1_strategy,'_',player_1],'file')>0;
            Osubmitted = exist(['TTT_Move',player_2_strategy,'_',player_2],'file')>0;
            if Xsubmitted && Osubmitted
                for run = runs
                    [results(run),player_1_times(run),player_2_times(run),player_1_error,player_2_error] = ...
                        TTT_Game_CvC(player_1,player_1_strategy,player_2,player_2_strategy);
                    if player_1_error+player_2_error > 0
                        Xsubmitted = Xsubmitted + player_1_error;
                        Osubmitted = Osubmitted + player_2_error;
                        break
                    end
                    if results(run) == 1
                        XWins = XWins + 1;
                    elseif results(run) == -1
                        OWins = OWins + 1;
                    else
                        Ties = Ties + 1;
                    end
                    XWinningPer(run)=XWins/run;
                    OWinningPer(run)=OWins/run;
                    TiePer(run)=Ties/run;
                end
                step = ceil(numOfGames/100);
                textOffSet = step;
                if step < 50
                    textOffSet = step/10;
                end
                plot(app.UIAxes,runs(step:step:end),XWinningPer(step:step:end),'-x');
                hold(app.UIAxes,'on');
                plot(app.UIAxes,runs(step:step:end),OWinningPer(step:step:end),'-o');
                plot(app.UIAxes,run+textOffSet,XWinningPer(end),'.');
                text(app.UIAxes,run+textOffSet,XWinningPer(end),[num2str(round(XWinningPer(end)*100,2)),'% - ',player_1]);
                plot(app.UIAxes,run+textOffSet,OWinningPer(end),'.');
                text(app.UIAxes,run+textOffSet,OWinningPer(end),[num2str(round(OWinningPer(end)*100,2)),'% - ',player_2]);
                legend(app.UIAxes,player_1,player_2);
                %             title(app.UIAxes,['Ties = ',num2str(round(TiePer(end)*100,2)),' %   Seed = ']);
                %plot(runs(1:step:end),TiePer(1:step:end),'-Square'); text(run+10,TiePer(end),num2str(TiePer(end)*100))
                hold(app.UIAxes,'off');
                player_1_time = sum(player_1_times);
                player_2_time = sum(player_2_times);
                player_1_ave_time = player_1_time/numOfGames;
                player_2_ave_time = player_2_time/numOfGames;
            else
                player_1_time = 0;
                player_2_time = 0;
                player_1_ave_time = player_1_time/numOfGames;
                player_2_ave_time = player_2_time/numOfGames;
                if Xsubmitted
                    XWins = numOfGames;
                    XWinningPer = 1;
                end
                if Osubmitted
                    OWins = numOfGames;
                    OWinningPer = 1;
                end
            end
            rmpath(genpath(['Moves/',player_1]));
            rmpath(genpath(['Moves/',player_2]));
            rmpath('Moves/Key');
            
            app.ResultsTable.Data = table({'X';'O'},{player_1;player_2},{player_1_strategy;player_2_strategy},{NoYes{Xsubmitted+1};NoYes{Osubmitted+1}}, ...
                [XWins;OWins], ...
                [numOfGames-XWins-Ties;numOfGames-OWins-Ties], ...
                [Ties;Ties], ...
                [XWinningPer(end);OWinningPer(end)], ...
                {sprintf('%0.6f',player_1_ave_time);sprintf('%0.6f',player_2_ave_time)}, ...
                {sprintf('%0.6f',player_1_time);sprintf('%0.6f',player_2_time)});
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             Roster = dataset('File','../MA310Roster.csv','Delimiter',',');
            RosterSize = length(app.Roster);
            RosterList = app.Roster.LastName;
            %             app.PlayerXDropDown.ItemsData = 1:RosterSize;
            %             app.PlayerODropDown.ItemsData = 1:RosterSize;
            app.PlayerXDropDown.Items = RosterList;
            nameSelected = app.PlayerXDropDown.Value;
            RosterList(strcmp(RosterList,nameSelected))=[];
            app.PlayerODropDown.Items = RosterList;
            %             for student = 1:RosterSize
            %                 LastName = Roster.LastName{student};
            %                 [ProgName,warn] = mFileExists(hwRoot,Inputs{inputset}{end},LastName);
            %                 addpath(genpath(LastName));
            %                 ProgFunc = str2func(ProgName);
            %                 rmpath(genpath(LastName));
            %             end
        end

        % Value changed function: PlayerXDropDown
        function PlayerXDropDownValueChanged(app, event)
            nameSelected = app.PlayerXDropDown.Value;
            TempRosterList = app.Roster.LastName;
            TempRosterList(strcmp(TempRosterList,nameSelected))=[];
            app.PlayerODropDown.Items=TempRosterList;
        end

        % Value changed function: PlayerODropDown
        function PlayerODropDownValueChanged(app, event)
            nameSelected = app.PlayerODropDown.Value;
            TempRosterList = app.Roster.LastName;
            TempRosterList(strcmp(TempRosterList,nameSelected))=[];
            app.PlayerXDropDown.Items=TempRosterList;
        end

        % Button pushed function: BattleButton
        function BattleButtonPushed(app, event)
            player_1 = app.PlayerXDropDown.Value;
            player_2 = app.PlayerODropDown.Value;
            player_1_strategy = getStrategy(app,1);
            player_2_strategy = getStrategy(app,2);
            TTT_Game_Simulator(app,player_1,player_1_strategy,player_2,player_2_strategy);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PlayerXDropDownLabel = uilabel(app.UIFigure);
            app.PlayerXDropDownLabel.Text = 'Player X :';
            app.PlayerXDropDown = uidropdown(app.UIFigure);
            app.PlayerXDropDown.Items = {'Option 1'};
            app.PlayerXDropDown.ValueChangedFcn = createCallbackFcn(app, @PlayerXDropDownValueChanged, true);
            app.PlayerODropDownLabel = uilabel(app.UIFigure);
            app.PlayerODropDownLabel.Text = 'Player O :';
            app.PlayerODropDown = uidropdown(app.UIFigure);
            app.PlayerODropDown.Items = {'Option 1'};
            app.PlayerODropDown.ValueChangedFcn = createCallbackFcn(app, @PlayerODropDownValueChanged, true);
            app.TicTacToeBattleLabel = uilabel(app.UIFigure);
            app.TicTacToeBattleLabel.Text = 'Tic-Tac-Toe Battle';
            app.XAISwitch = uiswitch(app.UIFigure, 'slider');
            app.XAISwitch.Value = 'On';
            app.OAISwitch = uiswitch(app.UIFigure, 'slider');
            app.OAISwitch.Value = 'On';
            app.NumberofGamesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofGamesEditFieldLabel.Text = 'Number of Games';
            app.NumberofGamesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofGamesEditField.Limits = [1 10000];
            app.NumberofGamesEditField.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Results')
            xlabel(app.UIAxes, 'Games')
            ylabel(app.UIAxes, 'Winning %')
            app.UIAxes.YLim = [0 1];
            app.BattleButton = uibutton(app.UIFigure, 'push');
            app.BattleButton.ButtonPushedFcn = createCallbackFcn(app, @BattleButtonPushed, true);
            app.BattleButton.Text = 'Battle';
            app.AIStrategyButtonGroup = uibuttongroup(app.UIFigure);
            app.AIStrategyButtonGroup.Title = 'AI Strategy';
            app.XAIButton1 = uiradiobutton(app.AIStrategyButtonGroup);
            app.XAIButton1.Text = '1';
            app.XAIButton2 = uiradiobutton(app.AIStrategyButtonGroup);
            app.XAIButton2.Text = '2';
            app.XAIButton2.Value = true;
            app.XAIButton3 = uiradiobutton(app.AIStrategyButtonGroup);
            app.XAIButton3.Text = '3';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.OAIButton1 = uiradiobutton(app.ButtonGroup);
            app.OAIButton1.Text = '1';
            app.OAIButton2 = uiradiobutton(app.ButtonGroup);
            app.OAIButton2.Text = '2';
            app.OAIButton2.Value = true;
            app.OAIButton3 = uiradiobutton(app.ButtonGroup);
            app.OAIButton3.Text = '3';
            app.AILabel = uilabel(app.UIFigure);
            app.AILabel.Text = 'AI';
            app.ResultsTable = uitable(app.UIFigure);
            app.ResultsTable.ColumnName = {''; 'Player'; 'Move'; 'Submitted'; 'Wins'; 'Loses'; 'Ties'; 'Winning %'; 'Average Run Time'; 'Total Run Time'};
            app.ResultsTable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = TTT_Battle

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end