classdef TTT_Battle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PlotUpdateFrequencyEditField  matlab.ui.control.NumericEditField
        PlotUpdateFrequencyEditFieldLabel  matlab.ui.control.Label
        RuntimeValueLabel             matlab.ui.control.Label
        TotalRuntimeLabel             matlab.ui.control.Label
        SimulationResultStatsLabel    matlab.ui.control.Label
        SwapPlayersButton             matlab.ui.control.Button
        ResultsTable                  matlab.ui.control.Table
        BattleButton                  matlab.ui.control.Button
        NumberofGamesEditField        matlab.ui.control.NumericEditField
        NumberofGamesEditFieldLabel   matlab.ui.control.Label
        TicTacToeBattleLabel          matlab.ui.control.Label
        PlayerODropDown               matlab.ui.control.DropDown
        PlayerODropDownLabel          matlab.ui.control.Label
        PlayerXDropDown               matlab.ui.control.DropDown
        PlayerXDropDownLabel          matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Roster = readtable('MA-310-Roster.csv','Delimiter',',');
        roster_list % List of lastnames
        strategy_root = 'Moves\';
        move_v1_program % Description
        move_v2_program % Description
        move_v3_program % Description
    end
    
    methods (Access = private)
        
        function strategy = getStrategy(app,player)
            if player == 1
                if app.XAIButton1.Value; strategy = '1'; end
                if app.XAIButton2.Value; strategy = '2'; end
                if app.XAIButton3.Value; strategy = '3'; end
            else
                if app.OAIButton1.Value; strategy = '1'; end
                if app.OAIButton2.Value; strategy = '2'; end
                if app.OAIButton3.Value; strategy = '3'; end
            end
        end
        
        function results = TTT_Game_Simulator(app,p1_strategy,p2_strategy,num_of_games,p1,p2)
            plot_step = app.PlotUpdateFrequencyEditField.Value;
            p1_error = 'No';
            p2_error = 'No';
            p1_avg_runtime = 0;
            p2_avg_runtime = 0;
            p1_tot_runtime = 0;
            p2_tot_runtime = 0;
            p1_win_count = 0;
            p2_win_count = 0;
            p1_lose_count = 0;
            p2_lose_count = 0;
            tie_count = 0;
            p1_moves_total = 0;
            p2_moves_total = 0;
            for run = 1:num_of_games
                % display_initial_board(app)
                [state.winner,p1_runtime,p1_moves,p2_runtime,p2_moves,error] = ttt_single_game_sim(app,p1_strategy,p2_strategy);
                if error == 1
                    % p1 errored
                    p1_error = 'Yes';
                elseif error == 2
                    % p2 errored
                    p2_error = 'Yes';
                end
                p1_moves_total = p1_moves_total + p1_moves;
                p2_moves_total = p2_moves_total + p2_moves;
                p1_tot_runtime = p1_tot_runtime + p1_runtime;
                p2_tot_runtime = p2_tot_runtime + p2_runtime;
                p1_avg_runtime = (p1_avg_runtime*run + p1_runtime)/(run+1);
                p2_avg_runtime = (p2_avg_runtime*run + p2_runtime)/(run+1);
                if state.winner == 1
                    p1_win_count = p1_win_count + 1;
                    p2_lose_count = p2_lose_count + 1;
                elseif state.winner == -1
                    p2_win_count = p2_win_count + 1;
                    p1_lose_count = p1_lose_count + 1;
                else
                    tie_count = tie_count + 1;
                end
                p1_win_pct = p1_win_count/run;
                p2_win_pct = p2_win_count/run;
                p1_lose_pct = p1_lose_count/run;
                p2_lose_pct = p2_lose_count/run;
                p1_time_ratio = p1_tot_runtime/(p1_tot_runtime + p2_tot_runtime);
                p2_time_ratio = p2_tot_runtime/(p1_tot_runtime + p2_tot_runtime);
                tie_pct = tie_count/run;
                if mod(run,plot_step) == 0
                    pause(0.001)
                    p = plot(app.UIAxes, ...
                        1, p1_win_pct,'x', ...
                        1, p2_win_pct,'o', ...
                        2, p1_lose_pct,'x', ...
                        2, p2_lose_pct,'o', ...
                        3, p1_time_ratio,'x', ...
                        3, p2_time_ratio,'o' ...
                        );
                    x_offset = -0.08;
                    y_offset = 0.00;
                    text(app.UIAxes,1+x_offset,p1_win_pct+y_offset,p1,'FontSize',22,'FontWeight','bold','VerticalAlignment','middle','HorizontalAlignment','right')
                    text(app.UIAxes,1+x_offset,p2_win_pct+y_offset,p2,'FontSize',22,'FontWeight','bold','VerticalAlignment','middle','HorizontalAlignment','right')
                    text(app.UIAxes,2+x_offset,p1_lose_pct+y_offset,p1,'FontSize',22,'FontWeight','bold','VerticalAlignment','middle','HorizontalAlignment','right')
                    text(app.UIAxes,2+x_offset,p2_lose_pct+y_offset,p2,'FontSize',22,'FontWeight','bold','VerticalAlignment','middle','HorizontalAlignment','right')
                    text(app.UIAxes,3+x_offset,p1_time_ratio+y_offset,p1,'FontSize',22,'FontWeight','bold','VerticalAlignment','middle','HorizontalAlignment','right')
                    text(app.UIAxes,3+x_offset,p2_time_ratio+y_offset,p2,'FontSize',22,'FontWeight','bold','VerticalAlignment','middle','HorizontalAlignment','right')
                    p(1).MarkerSize = 16; p(1).LineWidth = 4;
                    p(2).MarkerSize = 13; p(2).LineWidth = 4;
                    p(3).MarkerSize = 16; p(3).LineWidth = 4;
                    p(4).MarkerSize = 13; p(4).LineWidth = 4;
                    p(5).MarkerSize = 16; p(5).LineWidth = 4;
                    p(6).MarkerSize = 13; p(6).LineWidth = 4;
                end
                %                 hold(app.UIAxes)
                %                 plot(app.UIAxes,1, p1_win_count,'x',2, p2_win_count,'o',3, tie_count,'+');
                %                 plot(app.UIAxes,2, p2_win_count,'o')
                %                 plot(app.UIAxes,3, tie_count,'+')
                %                 hold(app.UIAxes)
            end
            results = {p1_win_count,p1_error,p2_win_count,p2_error,tie_count,p1_avg_runtime,p2_avg_runtime, p1_tot_runtime,p2_tot_runtime};
        end
        
        function [winner,p1_runtime,p1_moves,p2_runtime,p2_moves,error] = ttt_single_game_sim(app,p1_move,p2_move)
            p1_runtime = 0;
            p2_runtime = 0;
            error = 0;
            winner = 0;
            % Set game properties
            state.data = zeros(3);
            state.round = 0;
            state.winner = 0;
            state.whos_turn = 'X';
            state.player = 1;
            state.empty_space = true(1,9);
            state.x_click = 0;
            state.y_click = 0;
            while winner == 0 && state.round < 9
                if state.player == 1
                    tic
                    try
                        selection = p1_move(state);
                    catch
                        error = 1;
                        selection = app.move_v1_program(state);
                    end
                    if isempty(selection) || selection < 1
                        selection = app.move_v1_program(state);
                    end
                    p1_runtime = p1_runtime + toc;
                else
                    tic
                    try
                        selection = p2_move(state);
                    catch
                        error = 2;
                        selection = app.move_v1_program(state);
                    end
                    if isempty(selection) || selection < 1
                        selection = app.move_v1_program(state);
                    end
                    p2_runtime = p2_runtime + toc;
                end
                state.round = state.round+1;
                winner = winner_check(state.data);
                
                [x_click, y_click] = index_2_click(selection);
        
                state.empty_space(selection) = false;
                state.data(selection) = state.player;
                state.x_click = x_click;
                state.y_click = y_click;
                % update_board(app,state)

                state.player = -state.player;
                if state.player == 1
                    state.whos_turn = 'X';
                else
                    state.whos_turn = 'O';
                end

            end
            p1_moves = ceil(state.round/2);
            p2_moves = floor(state.round/2);


            function [x, y] = index_2_click(abs_index)

                idx_2_x_map = [ 1 1 1 2 2 2 3 3 3 ];
                idx_2_y_map = [ 3 2 1 3 2 1 3 2 1 ];
                x = idx_2_x_map(abs_index);
                y = idx_2_y_map(abs_index);
        
            end

            function result = winner_check(board)
          
                values(1:3) = sum(board, 2);
                values(4:6) = sum(board)';
                values(7)   = sum(board([1 5 9]));
                values(8)   = sum(board([3 5 7]));
                
                if any(values == 3)
                    result = 1;
                elseif any(values == -3)
                    result = -1;
                else
                    result = 0;
                end
              
            end

        end
        
        function display_initial_board(~)

            figure('Position',[100, 100, 650, 600]);
            hold on
            plot([2 2], [1 4], 'k', 'LineWidth', 3);
            plot([3 3], [1 4], 'k', 'LineWidth', 3);
            plot([1 4], [2 2], 'k', 'LineWidth', 3);
            plot([1 4], [3 3], 'k', 'LineWidth', 3);
            axis([0.5 4.5 0.5 4.5]);
            grid off
            axis equal;
            ax = gca; ax.XColor = 'none'; ax.YColor = 'none';

        end

        function update_board(~,state)
        
            if state.player == 1
            
                text( ...
                    state.x_click + 0.206, ...
                    state.y_click + 0.525, ...
                    'X', 'fontsize', 80, 'color', 'blue' ...
                    )
        
            else
        
                text( ...
                    state.x_click + 0.156, ...
                    state.y_click + 0.525, ...
                    'O', 'fontsize', 80, 'color', 'red' ...
                    )
            end
        
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.roster_list = ['v1';'v2';'v3';app.Roster.LastName];
            
            app.PlayerXDropDown.Items = app.roster_list;
            app.PlayerODropDown.Items = app.roster_list;
            
            addpath(app.strategy_root);
            app.move_v1_program = str2func('ai_v1');
            app.move_v2_program = str2func('ai_v2');
            app.move_v3_program = str2func('ai_v3');
            rmpath(app.strategy_root);
        end

        % Value changed function: PlayerXDropDown
        function PlayerXDropDownValueChanged(app, event)
%             name_selected = app.PlayerXDropDown.Value;
%             temp_list = app.roster_list;
%             temp_list(strcmp(temp_list,name_selected))=[];
%             app.PlayerODropDown.Items=temp_list;
        end

        % Value changed function: PlayerODropDown
        function PlayerODropDownValueChanged(app, event)
%             name_selected = app.PlayerODropDown.Value;
%             temp_list = app.Roster.LastName;
%             temp_list(strcmp(temp_list,name_selected))=[];
%             app.PlayerXDropDown.Items=temp_list;
        end

        % Button pushed function: BattleButton
        function BattleButtonPushed(app, event)
            removeStyle(app.ResultsTable);
            rng(77)
            addpath(app.strategy_root);
            p1_name = app.PlayerXDropDown.Value;
            p2_name = app.PlayerODropDown.Value;
            if strcmp(p1_name,'v1') || strcmp(p1_name,'v2') || strcmp(p1_name,'v3') ...
                p1_move = str2func(['ai_',p1_name]);
            else
                p1_move = str2func(['HW5P2_my_ai_move_',p1_name]);
            end
            if strcmp(p2_name,'v1') || strcmp(p2_name,'v2') || strcmp(p2_name,'v3') ...
                p2_move = str2func(['ai_',p2_name]);
            else
                p2_move = str2func(['HW5P2_my_ai_move_',p2_name]);
            end
            
            num_of_games = app.NumberofGamesEditField.Value;
            
            t = cputime;
            sim_results = TTT_Game_Simulator(app,p1_move,p2_move,num_of_games,p1_name,p2_name);
            t = cputime - t;
            rmpath(app.strategy_root);
            
            ties = sim_results{5};
            p1wins = sim_results{1};
            p1winsties = p1wins+ties;
            p1losses = num_of_games - p1winsties;
            p1winpct = round(p1wins/num_of_games,3);
            p1wintiepct = round(p1winsties/num_of_games,3);
            p1losspct = round(p1losses/num_of_games,3);
            p1err = sim_results{2};
            p1_avg_runtime = sim_results{6};
            p1_tot_runtime = sim_results{8};
            
            p2wins = sim_results{3};
            p2winsties = p2wins+ties;
            p2losses = num_of_games - p2winsties;
            p2winpct = round(p2wins/num_of_games,3);
            p2wintiepct = round(p2winsties/num_of_games,3);
            p2losspct = round(p2losses/num_of_games,3);
            p2err = sim_results{4};
            p2_avg_runtime = sim_results{7};
            p2_tot_runtime = sim_results{9};
            
            if strcmp(p1_name,'v1') || strcmp(p1_name,'v2')
                p1_name = ['move ',p1_name];
            end
            if strcmp(p2_name,'v1') || strcmp(p2_name,'v2')
                p2_name = ['move ',p2_name];
            end
            p1stats = {'', ...
                p1_name, ...
                p1err, ...
                num2str(p1wins), ...
                num2str(p1losses), ...
                num2str(ties), ...
                num2str(p1winpct*100), ...
                num2str(p1wintiepct*100), ...
                num2str(p1losspct*100), ...
                num2str(p1_avg_runtime*100000), ...
                num2str(p1_tot_runtime*100000) ...
                };
            p2stats = {'', ...
                p2_name, ...
                p2err, ...
                num2str(p2wins), ...
                num2str(p2losses), ...
                num2str(ties), ...
                num2str(p2winpct*100), ...
                num2str(p2wintiepct*100), ...
                num2str(p2losspct*100), ...
                num2str(p2_avg_runtime*100000), ...
                num2str(p2_tot_runtime*100000) ...
                };
            app.ResultsTable.Data = p1stats;
            app.ResultsTable.Data = [app.ResultsTable.Data;p2stats];
            
            s1 = uistyle('BackgroundColor',[0.1 0.8 0.2]);
            
            if p1wintiepct > p2wintiepct

                addStyle(app.ResultsTable,s1,'cell',[1,2]);
                addStyle(app.ResultsTable,s1,'cell',[1,7]);

            elseif p1wintiepct < p2wintiepct

                addStyle(app.ResultsTable,s1,'cell',[2,2]);
                addStyle(app.ResultsTable,s1,'cell',[2,7]);

            else

                addStyle(app.ResultsTable,s1,'cell',[1,2]);
                addStyle(app.ResultsTable,s1,'cell',[1,7]);
                addStyle(app.ResultsTable,s1,'cell',[2,2]);
                addStyle(app.ResultsTable,s1,'cell',[2,7]);

            end

            s2 = uistyle('BackgroundColor',[0.1 0.3 0.8]);
            
            % if round(p1_avg_runtime - p2_avg_runtime, 4) < 0
            if round(p1_avg_runtime/p2_avg_runtime,2) >= 1

                fast_name = p1_name;
                fast_time = num2str(round(p1_avg_runtime/p2_avg_runtime,2));
                slow_name = p2_name;

                addStyle(app.ResultsTable,s2,'cell',[1,11]);
                addStyle(app.ResultsTable,s2,'cell',[1,10]);

            % elseif round(p1_avg_runtime - p2_avg_runtime, 4) > 0
            elseif round(p2_avg_runtime/p1_avg_runtime,2) > 1

                
                fast_name = p2_name;
                fast_time = num2str(round(p2_avg_runtime/p1_avg_runtime,2));
                slow_name = p1_name;
                
                addStyle(app.ResultsTable,s2,'cell',[2,11]);
                addStyle(app.ResultsTable,s2,'cell',[2,10]);

            else

                addStyle(app.ResultsTable,s2,'cell',[1,11]);
                addStyle(app.ResultsTable,s2,'cell',[1,10]);
                addStyle(app.ResultsTable,s2,'cell',[2,11]);
                addStyle(app.ResultsTable,s2,'cell',[2,10]);

            end

            app.RuntimeValueLabel.Text = [num2str(round(t,2)),' seconds -- ' fast_name ' was ' fast_time ' times faster than ' slow_name];
        end

        % Button pushed function: SwapPlayersButton
        function SwapPlayersButtonPushed(app, event)
            p1_selected = app.PlayerXDropDown.Value;
            p2_selected = app.PlayerODropDown.Value;
            app.PlayerXDropDown.Value = p2_selected;
            app.PlayerODropDown.Value = p1_selected;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Player X Results')
            ylabel(app.UIAxes, 'Winning %')
            app.UIAxes.XLim = [0 4];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [1 2 3];
            app.UIAxes.XTickLabel = {'win'; 'loss'; 'time ratio'};
            app.UIAxes.YTick = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.UIAxes.YTickLabel = {'0'; ''; '10'; ''; '20'; ''; '30'; ''; '40'; ''; '50'; ''; '60'; ''; '70'; ''; '80'; ''; '90'; ''; '100'};
            app.PlayerXDropDownLabel = uilabel(app.UIFigure);
            app.PlayerXDropDownLabel.Text = 'Player X :';
            app.PlayerXDropDown = uidropdown(app.UIFigure);
            app.PlayerXDropDown.Items = {'Option 1'};
            app.PlayerXDropDown.ValueChangedFcn = createCallbackFcn(app, @PlayerXDropDownValueChanged, true);
            app.PlayerODropDownLabel = uilabel(app.UIFigure);
            app.PlayerODropDownLabel.Text = 'Player O :';
            app.PlayerODropDown = uidropdown(app.UIFigure);
            app.PlayerODropDown.Items = {'Option 1'};
            app.PlayerODropDown.ValueChangedFcn = createCallbackFcn(app, @PlayerODropDownValueChanged, true);
            app.TicTacToeBattleLabel = uilabel(app.UIFigure);
            app.TicTacToeBattleLabel.Text = 'Tic-Tac-Toe Battle';
            app.NumberofGamesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofGamesEditFieldLabel.Text = 'Number of Games';
            app.NumberofGamesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofGamesEditField.Limits = [1 100000];
            app.NumberofGamesEditField.ValueDisplayFormat = '%11.6g';
            app.NumberofGamesEditField.Value = 1000;
            app.BattleButton = uibutton(app.UIFigure, 'push');
            app.BattleButton.ButtonPushedFcn = createCallbackFcn(app, @BattleButtonPushed, true);
            app.BattleButton.Text = 'Battle';
            app.ResultsTable = uitable(app.UIFigure);
            app.ResultsTable.ColumnName = {''; 'Player'; 'Error'; 'W'; 'L'; 'T'; 'W %'; 'W+T %'; 'L %'; 'avg runtime'; 'runtim'};
            app.ResultsTable.RowName = {};
            app.SwapPlayersButton = uibutton(app.UIFigure, 'push');
            app.SwapPlayersButton.ButtonPushedFcn = createCallbackFcn(app, @SwapPlayersButtonPushed, true);
            app.SwapPlayersButton.Text = 'Swap Players';
            app.SimulationResultStatsLabel = uilabel(app.UIFigure);
            app.SimulationResultStatsLabel.Text = 'Simulation Result Stats';
            app.TotalRuntimeLabel = uilabel(app.UIFigure);
            app.TotalRuntimeLabel.Text = 'Total Runtime = ';
            app.RuntimeValueLabel = uilabel(app.UIFigure);
            app.RuntimeValueLabel.Text = '';
            app.PlotUpdateFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.PlotUpdateFrequencyEditFieldLabel.Text = 'Plot Update Frequency';
            app.PlotUpdateFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotUpdateFrequencyEditField.Limits = [1 100000];
            app.PlotUpdateFrequencyEditField.ValueDisplayFormat = '%11.6g';
            app.PlotUpdateFrequencyEditField.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTT_Battle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end