classdef WAM2_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Button_1             matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        Button_3             matlab.ui.control.Button
        Button_4             matlab.ui.control.Button
        Button_5             matlab.ui.control.Button
        Button_6             matlab.ui.control.Button
        Button_7             matlab.ui.control.Button
        Button_8             matlab.ui.control.Button
        Button_9             matlab.ui.control.Button
        WhackAMoleLabel      matlab.ui.control.Label
        PlayButton           matlab.ui.control.Button
        QuitButton           matlab.ui.control.Button
        LevelEditFieldLabel  matlab.ui.control.Label
        LevelEditField       matlab.ui.control.NumericEditField
        WaveEditFieldLabel   matlab.ui.control.Label
        WaveEditField        matlab.ui.control.NumericEditField
        ScoreEditFieldLabel  matlab.ui.control.Label
        ScoreEditField       matlab.ui.control.NumericEditField
        WavesEditFieldLabel  matlab.ui.control.Label
        WavesEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MoleButtons  % Description
    end
    
    methods (Access = private)
        
        function playLoop(app)
            level = app.LevelEditField.Value;
            waves = app.WavesEditField.Value; % max number of waves
            wave = 1; % initialize current wave
            while wave <= waves
                app.WaveEditField.Value = wave;     % display current wave
                molePopUpLocation = randi(9);       % get mole pop up spot
                showMole(app,molePopUpLocation);
                pause((10-level)/4);                % give the playersome time to hit the mole
                removeMole(app,molePopUpLocation);
                pause(1);                           % short break between pop ups
                wave = wave + 1;                    % get next wave value
            end
        end
        
        function  showMole(app,loc)
            enableAButton(app,loc);
            app.MoleButtons{loc}.Icon = 'WhackAMoleFace.png';
            %             switch moleLoc
            %                 case 1; app.Button_1.Icon = 'WhackAMoleFace.png';
            %                 case 2; app.Button_2.Icon = 'WhackAMoleFace.png';
            %                 case 3; app.Button_3.Icon = 'WhackAMoleFace.png';
            %                 case 4; app.Button_4.Icon = 'WhackAMoleFace.png';
            %                 case 5; app.Button_5.Icon = 'WhackAMoleFace.png';
            %                 case 6; app.Button_6.Icon = 'WhackAMoleFace.png';
            %                 case 7; app.Button_7.Icon = 'WhackAMoleFace.png';
            %                 case 8; app.Button_8.Icon = 'WhackAMoleFace.png';
            %                 case 9; app.Button_9.Icon = 'WhackAMoleFace.png';
            %             end
        end
    
        function  removeMole(app,loc)
            app.MoleButtons{loc}.Icon = '';
            %             switch moleLoc
            %                 case 1; app.Button_1.Icon = '';
            %                 case 2; app.Button_2.Icon = '';
            %                 case 3; app.Button_3.Icon = '';
            %                 case 4; app.Button_4.Icon = '';
            %                 case 5; app.Button_5.Icon = '';
            %                 case 6; app.Button_6.Icon = '';
            %                 case 7; app.Button_7.Icon = '';
            %                 case 8; app.Button_8.Icon = '';
            %                 case 9; app.Button_9.Icon = '';
            %             end
            disableButtons(app,loc)
        end
    
        function  disableButtons(app,loc)
            if nargin == 2
                app.MoleButtons{loc}.Enable = 0;
            else
                for i = 1:9
                    app.MoleButtons{i}.Enable = 0;
                end
            end
%             app.Button_1.Enable = 0;
%             app.Button_2.Enable = 0;
%             app.Button_3.Enable = 0;
%             app.Button_4.Enable = 0;
%             app.Button_5.Enable = 0;
%             app.Button_6.Enable = 0;
%             app.Button_7.Enable = 0;
%             app.Button_8.Enable = 0;
%             app.Button_9.Enable = 0;
        end
        function  enableAButton(app,loc)
            app.MoleButtons{loc}.Enable = 1;
            %                 switch buttonNum
            %                 case 1; app.Button_1.Enable = 1;
            %                 case 2; app.Button_2.Enable = 1;
            %                 case 3; app.Button_3.Enable = 1;
            %                 case 4; app.Button_4.Enable = 1;
            %                 case 5; app.Button_5.Enable = 1;
            %                 case 6; app.Button_6.Enable = 1;
            %                 case 7; app.Button_7.Enable = 1;
            %                 case 8; app.Button_8.Enable = 1;
            %                 case 9; app.Button_9.Enable = 1;
            %                 end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MoleButtons{1}=app.Button_1;
            app.MoleButtons{2}=app.Button_2;
            app.MoleButtons{3}=app.Button_3;
            app.MoleButtons{4}=app.Button_4;
            app.MoleButtons{5}=app.Button_5;
            app.MoleButtons{6}=app.Button_6;
            app.MoleButtons{7}=app.Button_7;
            app.MoleButtons{8}=app.Button_8;
            app.MoleButtons{9}=app.Button_9;
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.WaveEditField.Value = 1;
            app.ScoreEditField.Value = 0;
            app.Button_5.Text = 'Get Ready';
            pause(2)
            app.Button_5.Text = 'GO!';
            pause(1)
            app.Button_5.Text = '';
            disableButtons(app);
            playLoop(app)
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            score = app.ScoreEditField.Value;
            if strcmp(app.Button_1.Icon,'WhackAMoleFace.png')
                score = score + 1;
                app.ScoreEditField.Value = score;
                app.Button_1.Enable = 0;
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            score = app.ScoreEditField.Value;
            if strcmp(app.Button_2.Icon,'WhackAMoleFace.png')
                score = score + 1;
                app.ScoreEditField.Value = score;
                app.Button_2.Enable = 0;
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            score = app.ScoreEditField.Value;
            if strcmp(app.Button_3.Icon,'WhackAMoleFace.png')
                score = score + 1;
                app.ScoreEditField.Value = score;
                app.Button_3.Enable = 0;
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            score = app.ScoreEditField.Value;
            if strcmp(app.Button_4.Icon,'WhackAMoleFace.png')
                score = score + 1;
                app.ScoreEditField.Value = score;
                app.Button_4.Enable = 0;
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            score = app.ScoreEditField.Value;
            if strcmp(app.Button_5.Icon,'WhackAMoleFace.png')
                score = score + 1;
                app.ScoreEditField.Value = score;
                app.Button_5.Enable = 0;
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            score = app.ScoreEditField.Value;
            if strcmp(app.Button_6.Icon,'WhackAMoleFace.png')
                score = score + 1;
                app.ScoreEditField.Value = score;
                app.Button_6.Enable = 0;
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            score = app.ScoreEditField.Value;
            if strcmp(app.Button_7.Icon,'WhackAMoleFace.png')
                score = score + 1;
                app.ScoreEditField.Value = score;
                app.Button_7.Enable = 0;
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            score = app.ScoreEditField.Value;
            if strcmp(app.Button_8.Icon,'WhackAMoleFace.png')
                score = score + 1;
                app.ScoreEditField.Value = score;
                app.Button_8.Enable = 0;
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            score = app.ScoreEditField.Value;
            if strcmp(app.Button_9.Icon,'WhackAMoleFace.png')
                score = score + 1;
                app.ScoreEditField.Value = score;
                app.Button_9.Enable = 0;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '';
            app.WhackAMoleLabel = uilabel(app.UIFigure);
            app.WhackAMoleLabel.Text = 'Whack-A-Mole';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.LevelEditFieldLabel = uilabel(app.UIFigure);
            app.LevelEditFieldLabel.Text = 'Level';
            app.LevelEditField = uieditfield(app.UIFigure, 'numeric');
            app.LevelEditField.Limits = [1 9];
            app.LevelEditField.Value = 1;
            app.WaveEditFieldLabel = uilabel(app.UIFigure);
            app.WaveEditFieldLabel.Text = 'Wave';
            app.WaveEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaveEditField.Limits = [1 100];
            app.WaveEditField.Editable = 'off';
            app.WaveEditField.Value = 1;
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScoreEditField.Editable = 'off';
            app.WavesEditFieldLabel = uilabel(app.UIFigure);
            app.WavesEditFieldLabel.Text = 'Waves';
            app.WavesEditField = uieditfield(app.UIFigure, 'numeric');
            app.WavesEditField.Limits = [1 100];
            app.WavesEditField.Value = 10;
        end
    end

    methods (Access = public)

        % Construct app
        function app = WAM2_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end